classdef AStarApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        RecalculationsField           matlab.ui.control.NumericEditField
        RecalculationsEditFieldLabel  matlab.ui.control.Label
        TimestepField                 matlab.ui.control.NumericEditField
        TimestepEditFieldLabel        matlab.ui.control.Label
        Graph                         matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        PresetsPanel                  matlab.ui.container.Panel
        sLabel                        matlab.ui.control.Label
        PauseField                    matlab.ui.control.NumericEditField
        PausebetweenstepsLabel        matlab.ui.control.Label
        ClearDayClimbButton           matlab.ui.control.Button
        TrickyLandingButton           matlab.ui.control.Button
        DefaultButton                 matlab.ui.control.Button
        CrowdedAirspaceButton         matlab.ui.control.Button
        TallBuildingsButton           matlab.ui.control.Button
        NoObstaclesButton             matlab.ui.control.Button
        FindPathButton                matlab.ui.control.StateButton
        ResetButton                   matlab.ui.control.Button
        FixedObstaclesListPanel       matlab.ui.container.Panel
        RemoveFixedButton             matlab.ui.control.Button
        FixedZField                   matlab.ui.control.NumericEditField
        ZLabel_2                      matlab.ui.control.Label
        AddFixedButton                matlab.ui.control.Button
        FixedRField                   matlab.ui.control.NumericEditField
        RLabel_2                      matlab.ui.control.Label
        FixedYField                   matlab.ui.control.NumericEditField
        YEditFieldLabel_2             matlab.ui.control.Label
        FixedBox                      matlab.ui.control.ListBox
        FixedXField                   matlab.ui.control.NumericEditField
        XEditField_3Label_2           matlab.ui.control.Label
        TargetPanel                   matlab.ui.container.Panel
        TargetZ                       matlab.ui.control.NumericEditField
        ZEditFieldLabel_2             matlab.ui.control.Label
        TargetY                       matlab.ui.control.NumericEditField
        YEditField_4Label_2           matlab.ui.control.Label
        TargetX                       matlab.ui.control.NumericEditField
        XEditFieldLabel_2             matlab.ui.control.Label
        StartPanel                    matlab.ui.container.Panel
        StartZ                        matlab.ui.control.NumericEditField
        ZEditFieldLabel               matlab.ui.control.Label
        StartY                        matlab.ui.control.NumericEditField
        YEditField_4Label             matlab.ui.control.Label
        StartX                        matlab.ui.control.NumericEditField
        XEditFieldLabel               matlab.ui.control.Label
        PauseButton                   matlab.ui.control.Button
        MovingObstaclesListPanel      matlab.ui.container.Panel
        DirectionDropDown             matlab.ui.control.DropDown
        DirectionDropDownLabel        matlab.ui.control.Label
        RemoveMovingButton            matlab.ui.control.Button
        MovingZField                  matlab.ui.control.NumericEditField
        ZLabel                        matlab.ui.control.Label
        AddMovingButton               matlab.ui.control.Button
        MovingRField                  matlab.ui.control.NumericEditField
        RLabel                        matlab.ui.control.Label
        MovingYField                  matlab.ui.control.NumericEditField
        YEditFieldLabel               matlab.ui.control.Label
        MovingBox                     matlab.ui.control.ListBox
        MovingXField                  matlab.ui.control.NumericEditField
        XEditField_3Label             matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Paused
        Test
        MovingObstacles = [25 15 5 32 35 20 5 33 30 30 5 34 15 15 4 35 25 15 5 36 13 10 4 37]; % x y r z
        FixedObstacles = [15 15 2 30 18 38 1 25 20 20 2 25 35 10 5 30 40 35 4 25 30 25 4 25]; % x y r z
        k
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
if app.Paused == 0
    app.Paused = 1; % If not paused, pause
    app.PauseButton.Text = 'Resume';
    drawnow
    app.ResetButton.Enable = 'on';
    uiwait(app.UIFigure)
else
    uiresume(app.UIFigure) % Otherwise, resume
    app.Paused = 0;
    app.PauseButton.Text = 'Pause';
    app.ResetButton.Enable = 'off';
end

        end

        % Value changed function: MovingBox
        function MovingBoxValueChanged(app, event)
            [~,i] = ismember(app.MovingBox.Value,app.MovingBox.Items); % Get obstacle number
            app.MovingXField.Value = app.MovingObstacles(i*4-3);
            app.MovingYField.Value = app.MovingObstacles(i*4-2);
            app.MovingRField.Value = app.MovingObstacles(i*4-1);
            app.MovingZField.Value = app.MovingObstacles(i*4);
        end

        % Button pushed function: AddMovingButton
        function AddMovingButtonPushed(app, event)
            new_i = length(app.MovingBox.Items)+1;
            app.MovingBox.Items(new_i) = cellstr(strjoin(["Obstacle" string(new_i)]));
            app.MovingBox.Value = app.MovingBox.Items(end);
            l = length(app.MovingObstacles)+1;
            app.MovingObstacles(l:l+3) = 0; %[app.MovingXField.Value app.MovingYField.Value app.MovingRField.Value app.MovingZField.Value];
            app.MovingXField.Value = app.MovingObstacles(l);
            app.MovingYField.Value = app.MovingObstacles(l+1);
            app.MovingRField.Value = app.MovingObstacles(l+2);
            app.MovingZField.Value = app.MovingObstacles(l+3);
        end

        % Button pushed function: RemoveMovingButton
        function RemoveMovingButtonPushed(app, event)
            [~,idx] = ismember(app.MovingBox.Value,app.MovingBox.Items);
            for i = idx+1:length(app.MovingBox.Items)
                app.MovingBox.Items(i) = cellstr(strjoin(["Obstacle" string(i-1)]));
            end
            app.MovingBox.Items(idx) = [];
            app.MovingObstacles(idx*4-3:idx*4)=[];
            
            % Update currently selected obstacle's coords
            [~,i] = ismember(app.MovingBox.Value,app.MovingBox.Items);
            app.MovingXField.Value = app.MovingObstacles(i*4-3);
            app.MovingYField.Value = app.MovingObstacles(i*4-2);
            app.MovingRField.Value = app.MovingObstacles(i*4-1);
            app.MovingZField.Value = app.MovingObstacles(i*4);
        end

        % Value changed function: MovingXField
        function MovingXFieldValueChanged(app, event)
            [~,idx] = ismember(app.MovingBox.Value,app.MovingBox.Items);
            app.MovingObstacles(idx*4-3) = app.MovingXField.Value;            
        end

        % Value changed function: MovingYField
        function MovingYFieldValueChanged(app, event)
            [~,idx] = ismember(app.MovingBox.Value,app.MovingBox.Items);
            app.MovingObstacles(idx*4-2) = app.MovingYField.Value; 
        end

        % Value changed function: MovingRField
        function MovingRFieldValueChanged(app, event)
            [~,idx] = ismember(app.MovingBox.Value,app.MovingBox.Items);
            app.MovingObstacles(idx*4-1) = app.MovingRField.Value; 
        end

        % Value changed function: MovingZField
        function MovingZFieldValueChanged(app, event)
            [~,idx] = ismember(app.MovingBox.Value,app.MovingBox.Items);
            app.MovingObstacles(idx*4) = app.MovingZField.Value; 
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            %uiwait(app.UIFigure)
            hold(app.Graph,'off')
            plot3(app.Graph,0,0,0);
            axis(app.Graph,[0 50 0 50 0 50])
            app.Paused = 0;
            app.PauseButton.Text = 'Pause';
            app.TimestepField.Value = 0;
            app.RecalculationsField.Value = 0;
            app.FindPathButton.Value = 0;
            app.FindPathButton.Enable = 'on';
            app.FindPathButton.Text = 'Find Path';
        end

        % Value changed function: FindPathButton
        function FindPathButtonValueChanged(app, event)
            app.FindPathButton.Enable = 'off';
            app.FindPathButton.Text = 'Calculating...';
            app.ResetButton.Enable = 'off';
            
                        % % INITIALISE % %

            %DEFINE THE 3-D MAP ARRAY
            MAX_X=50;
            MAX_Y=50;
            MAX_Z=50;
            MAP=2*(ones(MAX_X,MAX_Y,MAX_Z));

            % % BEGIN Manual Start, Obstacle, Target Input
            % Obstacle=-1,Target = 0,UAM=1,Space=2
            start = [app.StartX.Value, app.StartY.Value, app.StartZ.Value];
            target = [app.TargetX.Value, app.TargetY.Value, app.TargetZ.Value];
            moving = app.MovingObstacles; % x y r z
            fixed = app.FixedObstacles; % x y r z
            numCyl = (length(fixed)+length(moving))/4;
            Obst_i = [];
            MAP(start(1),start(2),start(3))=1;
            MAP(target(1),target(2),target(3))=0;
            xval = start(1);
            yval = start(2);
            zval = start(3);
            Obst_i = [];
            cylCoord = [];

            dirInput = app.DirectionDropDown.Value;
            [obsDirec,uamDirecX,uamDirecY,uamDirecZ] = direction(dirInput,start,target);
            
            app.Paused = 0;

                        % % CYLCOORD
            a=1;
            k2=1;
            tic
            for k=1:numCyl % Obtain obstacles within cylinder
                if k <= length(moving)/4 % Populate top of list with moving cylinder coords
                    cylCoord(k,1) = moving(k*4-3);
                    cylCoord(k,2) = moving(k*4-2);
                    cylCoord(k,3) = moving(k*4-1);
                    cylCoord(k,4) = moving(k*4);
                else
                    cylCoord(k,1) = fixed(k2*4-3); % Populate bottom of list with fixed cylinder coords
                    cylCoord(k,2) = fixed(k2*4-2);
                    cylCoord(k,3) = fixed(k2*4-1);
                    cylCoord(k,4) = fixed(k2*4);
                    k2=k2+1;
                end

                [x(:,:,k), y(:,:,k), z(:,:,k)] = cylinder(cylCoord(k,3),50); %Define Cylinder
                x(:,:,k) = x(:,:,k)+cylCoord(k,1);
                y(:,:,k) = y(:,:,k)+cylCoord(k,2);
                if k <= length(moving)/4 % if aircraft then thin cylinder at altitude
                    z(1,:,k) = cylCoord(k,4)+0.5;
                    z(2,:,k) = cylCoord(k,4)-0.5;
                else
                    z(2,:,k) = cylCoord(k,4); % if building then cylinder up to altitude
                end

                for i=1:MAX_X
                    for j=1:MAX_Y
                        % Grab obstacles near radius of cylinder, add to closed list and plot
                        if distance(cylCoord(k,1),cylCoord(k,2),i,j) < cylCoord(k,3)+.3
                            if k <= length(moving)/4
                                MAP(i,j,cylCoord(k,4))=-1;
                                Obst(a,1)=i;
                                Obst(a,2)=j;
                                Obst(a,3)=cylCoord(k,4);
                                a=a+1;
                            else
                                if isempty(Obst_i)
                                    Obst_i = a-1;
                                end
                                MAP(i,j,1:cylCoord(k,4))=-1;
                                a2=a+cylCoord(k,4)-1;
                                Obst(a:a2,1)=i;
                                Obst(a:a2,2)=j;
                                Obst(a:a2,3)=1:cylCoord(k,4);
                                a=a2+1;
                            end
                        end
                    end
                end
            end
            toc
            if isempty(Obst_i) && isempty(cylCoord) == 0
                Obst_i = length(Obst);
            end
            if MAP(target(1),target(2),target(3)) == -1
                errordlg('Target is within obstacle');
            end
                        % FIND INITIAL PATH %
                        
            run A_Star1_3D.m
            opsize = size(Optimal_path,1);
            for a=1:opsize % Add path to OPM
                Opt_path_master(a,:)=Optimal_path(opsize+1-a,:);
            end
            recalc_counter = 0;
                        
                        % PLOT START CONDITIONS %
            
            plot3(app.Graph,0,0,0)
            axis(app.Graph,[1 MAX_X+1 1 MAX_Y+1 1 MAX_Z+1])
            grid(app.Graph,'on')
            hold(app.Graph,'on')

            plot3(app.Graph,start(1),start(2),start(3),'md','MarkerSize',10); %% Plot Start
            UAM=plot3(app.Graph,start(1),start(2),start(3),'bo','MarkerSize',8); %% Plot UAM
            plot3(app.Graph,target(1),target(2),target(3),'gd','MarkerSize',10); %% Plot Target
            path = plot3(app.Graph,Opt_path_master(:,1),Opt_path_master(:,2),Opt_path_master(:,3),'k-','LineWidth',1); % Initialise Path
            if isempty(moving) == 0 || isempty(fixed) == 0
                for k=1:numCyl
                    cylFig(k)=surf(app.Graph,x(:,:,k),y(:,:,k),z(:,:,k),'FaceColor','r','EdgeColor','none','FaceAlpha',1);
                end
                if isempty(moving) == 0
                    centObst = plot3(app.Graph,cylCoord(1:length(moving)/4,1),cylCoord(1:length(moving)/4,2),cylCoord(1:length(moving)/4,4),'r^'); %% Plot airplanes
                end
            end
                        % END INITIALISATION %

                        % % BEGIN LOOP % %
            t=1;
            app.TimestepField.Value = t;
            
            while ( (get(UAM,'XData') ~= target(1) || get(UAM,'YData') ~= target(2))...
                    || get(UAM,'ZData') ~= target(3) ) % If target not reached
                for a=1:Obst_i
                    if ( (1 <= Obst(a,1)+obsDirec(1)) && (Obst(a,1)+obsDirec(1) <= MAX_X) &&...
                            (1 <= Obst(a,2)+obsDirec(2)) && (Obst(a,2)+obsDirec(2) <= MAX_Y) )
                        MAP(Obst(a,1),Obst(a,2),Obst(a,3)) = 2;
                        Obst(a,1)=Obst(a,1)+obsDirec(1);
                        Obst(a,2)=Obst(a,2)+obsDirec(2);
                    end
                end
                for a=1:Obst_i
                    MAP(Obst(a,1),Obst(a,2),Obst(a,3)) = -1;
                end
                if MAP(target(1),target(2),target(3)) == -1
                    errordlg('Target is within obstacle');
                end
                for a=t:length(Opt_path_master)
                    for b=1:Obst_i
                        if ( (Opt_path_master(a,1) == Obst(b,1) && Opt_path_master(a,2) == Obst(b,2))...
                                && Opt_path_master(a,3) == Obst(b,3) )
                            start(1) = Opt_path_master(t,1);
                            start(2) = Opt_path_master(t,2);
                            start(3) = Opt_path_master(t,3);
                            run A_Star1_3D.m
                            opsize = size(Optimal_path,1);
                            t=t+1;
                            Opt_path_master(t:t+opsize-1,1)=flip(Optimal_path(:,1));
                            Opt_path_master(t:t+opsize-1,2)=flip(Optimal_path(:,2));
                            Opt_path_master(t:t+opsize-1,3)=flip(Optimal_path(:,3));
                            recalc_counter=recalc_counter+1;
                            app.RecalculationsField.Value = recalc_counter;
                        end
                    end
                end

                            % % Plot results
                            
                pause(app.PauseField.Value)
                if isempty(moving) == 0 % If moving cylinders exist
                    x(:,:,1:length(moving)/4) = x(:,:,1:length(moving)/4)+obsDirec(1);%% Move obstacles
                    y(:,:,1:length(moving)/4) = y(:,:,1:length(moving)/4)+obsDirec(2);
                    for k = 1:numCyl
                        set(cylFig(k),'XData',x(:,:,k),'YData',y(:,:,k));
                    end
                    cylCoord(:,1) = cylCoord(:,1)+obsDirec(1);
                    cylCoord(:,2) = cylCoord(:,2)+obsDirec(2);
                    set(centObst,'XData',cylCoord(1:length(moving)/4,1),'YData',cylCoord(1:length(moving)/4,2))
                end
                set(path,'XData',Opt_path_master(:,1),'YData',Opt_path_master(:,2),'ZData',Opt_path_master(:,3)) %% Draw path
                set(UAM,'XData',Opt_path_master(t,1),'YData',Opt_path_master(t,2),'ZData',Opt_path_master(t,3)) %% Move aircraft
                drawnow

                t = t+1;
                app.TimestepField.Value = t;
            end

            app.FindPathButton.Value = 0;
            app.FindPathButton.Enable = 'on';
            app.FindPathButton.Text = 'Find Path';
            app.ResetButton.Enable = 'on';
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
            app.StartX.Value = 10;
            app.StartY.Value = 40;
            app.StartZ.Value = 33;
            
            app.TargetX.Value = 45;
            app.TargetY.Value = 15;
            app.TargetZ.Value = 33;
            
            app.MovingObstacles = [25 15 5 32 35 20 5 33 30 30 5 34 15 15 4 35 25 15 5 36 13 10 4 37];
            app.MovingBox.Items = {'Obstacle 1','Obstacle 2','Obstacle 3','Obstacle 4','Obstacle 5','Obstacle 6'};
            app.MovingBox.Value = app.MovingBox.Items(1);
            [~,i] = ismember(app.MovingBox.Value,app.MovingBox.Items);
            app.MovingXField.Value = app.MovingObstacles(i*4-3);
            app.MovingYField.Value = app.MovingObstacles(i*4-2);
            app.MovingRField.Value = app.MovingObstacles(i*4-1);
            app.MovingZField.Value = app.MovingObstacles(i*4);
            app.DirectionDropDown.Value = 'N';
            
            app.FixedObstacles = [15 15 2 30 18 38 2 34 15 30 2 35 35 10 5 40 40 35 4 25 30 25 4 25];
            app.FixedBox.Items = {'Obstacle 1','Obstacle 2','Obstacle 3','Obstacle 4','Obstacle 5','Obstacle 6'};
            app.FixedBox.Value = app.FixedBox.Items(1);            
            [~,i] = ismember(app.FixedBox.Value,app.FixedBox.Items);
            app.FixedXField.Value = app.FixedObstacles(i*4-3);
            app.FixedYField.Value = app.FixedObstacles(i*4-2);
            app.FixedRField.Value = app.FixedObstacles(i*4-1);
            app.FixedZField.Value = app.FixedObstacles(i*4);
        end

        % Button pushed function: AddFixedButton
        function AddFixedButtonPushed(app, event)
            new_i = length(app.FixedBox.Items)+1;
            app.FixedBox.Items(new_i) = cellstr(strjoin(["Obstacle" string(new_i)]));
            app.FixedBox.Value = app.FixedBox.Items(end);
            l = length(app.FixedObstacles)+1;
            app.FixedObstacles(l:l+3) = 0;
            app.FixedXField.Value = app.FixedObstacles(l);
            app.FixedYField.Value = app.FixedObstacles(l+1);
            app.FixedRField.Value = app.FixedObstacles(l+2);
            app.FixedZField.Value = app.FixedObstacles(l+3);
        end

        % Button pushed function: RemoveFixedButton
        function RemoveFixedButtonPushed(app, event)
            [~,idx] = ismember(app.FixedBox.Value,app.FixedBox.Items);
            for i = idx+1:length(app.FixedBox.Items)
                app.FixedBox.Items(i) = cellstr(strjoin(["Obstacle" string(i-1)]));
            end
            app.FixedBox.Items(idx) = [];
            app.FixedObstacles(idx*4-3:idx*4)=[];
            
            % Update currently selected obstacle's coords
            [~,i] = ismember(app.FixedBox.Value,app.FixedBox.Items);
            app.FixedXField.Value = app.FixedObstacles(i*4-3);
            app.FixedYField.Value = app.FixedObstacles(i*4-2);
            app.FixedRField.Value = app.FixedObstacles(i*4-1);
            app.FixedZField.Value = app.FixedObstacles(i*4);
        end

        % Value changed function: FixedXField
        function FixedXFieldValueChanged(app, event)
            [~,idx] = ismember(app.FixedBox.Value,app.FixedBox.Items);
            app.FixedObstacles(idx*4-3) = app.FixedXField.Value;    
        end

        % Value changed function: FixedYField
        function FixedYFieldValueChanged(app, event)
            [~,idx] = ismember(app.FixedBox.Value,app.FixedBox.Items);
            app.FixedObstacles(idx*4-2) = app.FixedYField.Value;   
        end

        % Value changed function: FixedRField
        function FixedRFieldValueChanged(app, event)
            [~,idx] = ismember(app.FixedBox.Value,app.FixedBox.Items);
            app.FixedObstacles(idx*4-1) = app.FixedRField.Value;   
        end

        % Value changed function: FixedZField
        function FixedZFieldValueChanged(app, event)
            [~,idx] = ismember(app.FixedBox.Value,app.FixedBox.Items);
            app.FixedObstacles(idx*4) = app.FixedZField.Value;   
        end

        % Button pushed function: NoObstaclesButton
        function NoObstaclesButtonPushed(app, event)
            app.StartX.Value = 10;
            app.StartY.Value = 10;
            app.StartZ.Value = 10;
            
            app.TargetX.Value = 40;
            app.TargetY.Value = 40;
            app.TargetZ.Value = 40;
            
            app.MovingObstacles = [];
            app.MovingBox.Items = {};
            app.MovingXField.Value = 0;
            app.MovingYField.Value = 0;
            app.MovingRField.Value = 0;
            app.MovingZField.Value = 0;
            
            app.FixedObstacles = [];
            app.FixedBox.Items = {};
            app.FixedXField.Value = 0;
            app.FixedYField.Value = 0;
            app.FixedRField.Value = 0;
            app.FixedZField.Value = 0;
        end

        % Button pushed function: ClearDayClimbButton
        function ClearDayClimbButtonPushed(app, event)
            app.StartX.Value = 40;
            app.StartY.Value = 10;
            app.StartZ.Value = 12;
            
            app.TargetX.Value = 10;
            app.TargetY.Value = 30;
            app.TargetZ.Value = 45;
            
            app.MovingObstacles = [25 35 5 40 29 28 7 39];
            app.MovingBox.Items = {'Obstacle 1','Obstacle 2'};
            app.MovingBox.Value = app.MovingBox.Items(1);
            [~,i] = ismember(app.MovingBox.Value,app.MovingBox.Items);
            app.MovingXField.Value = app.MovingObstacles(i*4-3);
            app.MovingYField.Value = app.MovingObstacles(i*4-2);
            app.MovingRField.Value = app.MovingObstacles(i*4-1);
            app.MovingZField.Value = app.MovingObstacles(i*4);
            app.DirectionDropDown.Value = 'SW';
            
            app.FixedObstacles = [33 11 5 30 38 18 6 35 30 4 4 33];
            app.FixedBox.Items = {'Obstacle 1','Obstacle 2','Obstacle 3'};
            app.FixedBox.Value = app.FixedBox.Items(1);            
            [~,i] = ismember(app.FixedBox.Value,app.FixedBox.Items);
            app.FixedXField.Value = app.FixedObstacles(i*4-3);
            app.FixedYField.Value = app.FixedObstacles(i*4-2);
            app.FixedRField.Value = app.FixedObstacles(i*4-1);
            app.FixedZField.Value = app.FixedObstacles(i*4);
        end

        % Button pushed function: TallBuildingsButton
        function TallBuildingsButtonPushed(app, event)
            app.StartX.Value = 10;
            app.StartY.Value = 10;
            app.StartZ.Value = 20;
            
            app.TargetX.Value = 45;
            app.TargetY.Value = 40;
            app.TargetZ.Value = 25;
            
            app.MovingObstacles = [];
            app.MovingBox.Items = {};
            app.MovingXField.Value = 0;
            app.MovingYField.Value = 0;
            app.MovingRField.Value = 0;
            app.MovingZField.Value = 0;
            app.DirectionDropDown.Value = 'N';
            
            app.FixedObstacles = [29 7 5 30 36 17 7 35 27 34 6 40 38 27 7 28 36 43 8 30 20 25 4 45 17 37 5 35];
            app.FixedBox.Items = {'Obstacle 1','Obstacle 2','Obstacle 3','Obstacle 4','Obstacle 5','Obstacle 6','Obstacle 7'};
            app.FixedBox.Value = app.FixedBox.Items(1);            
            [~,i] = ismember(app.FixedBox.Value,app.FixedBox.Items);
            app.FixedXField.Value = app.FixedObstacles(i*4-3);
            app.FixedYField.Value = app.FixedObstacles(i*4-2);
            app.FixedRField.Value = app.FixedObstacles(i*4-1);
            app.FixedZField.Value = app.FixedObstacles(i*4);
        end

        % Button pushed function: CrowdedAirspaceButton
        function CrowdedAirspaceButtonPushed(app, event)
            app.StartX.Value = 45;
            app.StartY.Value = 20;
            app.StartZ.Value = 35;
            
            app.TargetX.Value = 13;
            app.TargetY.Value = 31;
            app.TargetZ.Value = 35;
            
            app.MovingObstacles = [30 23 6 35 44 31 6 36 29 35 7 36 40 40 6 35 15 42 5 35 25 8 7 34 19 22 5 34];
            app.MovingBox.Items = {'Obstacle 1','Obstacle 2','Obstacle 3','Obstacle 4','Obstacle 5','Obstacle 6', 'Obstacle 7'};
            app.MovingBox.Value = app.MovingBox.Items(1);
            [~,i] = ismember(app.MovingBox.Value,app.MovingBox.Items);
            app.MovingXField.Value = app.MovingObstacles(i*4-3);
            app.MovingYField.Value = app.MovingObstacles(i*4-2);
            app.MovingRField.Value = app.MovingObstacles(i*4-1);
            app.MovingZField.Value = app.MovingObstacles(i*4);
            app.DirectionDropDown.Value = 'SW';
            
            app.FixedObstacles = [];
            app.FixedBox.Items = {};
            app.FixedXField.Value = 0;
            app.FixedYField.Value = 0;
            app.FixedRField.Value = 0;
            app.FixedZField.Value = 0;
        end

        % Button pushed function: TrickyLandingButton
        function TrickyLandingButtonPushed(app, event)
            app.StartX.Value = 37;
            app.StartY.Value = 17;
            app.StartZ.Value = 40;
            
            app.TargetX.Value = 19;
            app.TargetY.Value = 27;
            app.TargetZ.Value = 20;
            
            app.MovingObstacles = [];
            app.MovingBox.Items = {};
            app.MovingXField.Value = 0;
            app.MovingYField.Value = 0;
            app.MovingRField.Value = 0;
            app.MovingZField.Value = 0;
            app.DirectionDropDown.Value = 'N';
            
            app.FixedObstacles = [24 23 4 35 15 22 4 40 25 32 5 45 14 33 7 32];
            app.FixedBox.Items = {'Obstacle 1','Obstacle 2','Obstacle 3','Obstacle 4'};
            app.FixedBox.Value = app.FixedBox.Items(1);            
            [~,i] = ismember(app.FixedBox.Value,app.FixedBox.Items);
            app.FixedXField.Value = app.FixedObstacles(i*4-3);
            app.FixedYField.Value = app.FixedObstacles(i*4-2);
            app.FixedRField.Value = app.FixedObstacles(i*4-1);
            app.FixedZField.Value = app.FixedObstacles(i*4);
        end

        % Value changed function: FixedBox
        function FixedBoxValueChanged(app, event)
            [~,i] = ismember(app.FixedBox.Value,app.FixedBox.Items); % Get obstacle number
            app.FixedXField.Value = app.FixedObstacles(i*4-3);
            app.FixedYField.Value = app.FixedObstacles(i*4-2);
            app.FixedRField.Value = app.FixedObstacles(i*4-1);
            app.FixedZField.Value = app.FixedObstacles(i*4);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {738, 738};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {823, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Graph = uiaxes(app.LeftPanel);
            xlabel(app.Graph, 'X')
            ylabel(app.Graph, 'Y')
            zlabel(app.Graph, 'Z')
            app.TimestepEditFieldLabel = uilabel(app.LeftPanel);
            app.TimestepEditFieldLabel.Text = 'Timestep';
            app.TimestepField = uieditfield(app.LeftPanel, 'numeric');
            app.RecalculationsEditFieldLabel = uilabel(app.LeftPanel);
            app.RecalculationsEditFieldLabel.Text = 'Recalculations';
            app.RecalculationsField = uieditfield(app.LeftPanel, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.MovingObstaclesListPanel = uipanel(app.RightPanel);
            app.MovingObstaclesListPanel.Title = 'Moving Obstacles List';
            app.XEditField_3Label = uilabel(app.MovingObstaclesListPanel);
            app.XEditField_3Label.Text = 'X';
            app.MovingXField = uieditfield(app.MovingObstaclesListPanel, 'numeric');
            app.MovingXField.Limits = [0 50];
            app.MovingXField.ValueChangedFcn = createCallbackFcn(app, @MovingXFieldValueChanged, true);
            app.MovingXField.Value = 25;
            app.MovingBox = uilistbox(app.MovingObstaclesListPanel);
            app.MovingBox.Items = {'Obstacle 1', 'Obstacle 2', 'Obstacle 3', 'Obstacle 4', 'Obstacle 5', 'Obstacle 6'};
            app.MovingBox.ValueChangedFcn = createCallbackFcn(app, @MovingBoxValueChanged, true);
            app.MovingBox.Value = 'Obstacle 1';
            app.YEditFieldLabel = uilabel(app.MovingObstaclesListPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.MovingYField = uieditfield(app.MovingObstaclesListPanel, 'numeric');
            app.MovingYField.Limits = [0 50];
            app.MovingYField.ValueChangedFcn = createCallbackFcn(app, @MovingYFieldValueChanged, true);
            app.MovingYField.Value = 15;
            app.RLabel = uilabel(app.MovingObstaclesListPanel);
            app.RLabel.Text = 'R';
            app.MovingRField = uieditfield(app.MovingObstaclesListPanel, 'numeric');
            app.MovingRField.Limits = [0 50];
            app.MovingRField.ValueChangedFcn = createCallbackFcn(app, @MovingRFieldValueChanged, true);
            app.MovingRField.Value = 5;
            app.AddMovingButton = uibutton(app.MovingObstaclesListPanel, 'push');
            app.AddMovingButton.ButtonPushedFcn = createCallbackFcn(app, @AddMovingButtonPushed, true);
            app.AddMovingButton.Text = '+';
            app.ZLabel = uilabel(app.MovingObstaclesListPanel);
            app.ZLabel.Text = 'Z';
            app.MovingZField = uieditfield(app.MovingObstaclesListPanel, 'numeric');
            app.MovingZField.Limits = [0 50];
            app.MovingZField.ValueChangedFcn = createCallbackFcn(app, @MovingZFieldValueChanged, true);
            app.MovingZField.Value = 32;
            app.RemoveMovingButton = uibutton(app.MovingObstaclesListPanel, 'push');
            app.RemoveMovingButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveMovingButtonPushed, true);
            app.RemoveMovingButton.Text = '-';
            app.DirectionDropDownLabel = uilabel(app.MovingObstaclesListPanel);
            app.DirectionDropDownLabel.Text = 'Direction';
            app.DirectionDropDown = uidropdown(app.MovingObstaclesListPanel);
            app.DirectionDropDown.Items = {'NW', 'N', 'NE', 'E', 'SE', 'S', 'SW', 'W'};
            app.DirectionDropDown.Value = 'N';
            app.PauseButton = uibutton(app.RightPanel, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.StartPanel = uipanel(app.RightPanel);
            app.StartPanel.Title = 'Start';
            app.XEditFieldLabel = uilabel(app.StartPanel);
            app.XEditFieldLabel.Text = 'X';
            app.StartX = uieditfield(app.StartPanel, 'numeric');
            app.StartX.Limits = [0 50];
            app.StartX.Value = 10;
            app.YEditField_4Label = uilabel(app.StartPanel);
            app.YEditField_4Label.Text = 'Y';
            app.StartY = uieditfield(app.StartPanel, 'numeric');
            app.StartY.Limits = [0 50];
            app.StartY.Value = 40;
            app.ZEditFieldLabel = uilabel(app.StartPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.StartZ = uieditfield(app.StartPanel, 'numeric');
            app.StartZ.Limits = [0 50];
            app.StartZ.Value = 33;
            app.TargetPanel = uipanel(app.RightPanel);
            app.TargetPanel.Title = 'Target';
            app.XEditFieldLabel_2 = uilabel(app.TargetPanel);
            app.XEditFieldLabel_2.Text = 'X';
            app.TargetX = uieditfield(app.TargetPanel, 'numeric');
            app.TargetX.Limits = [0 50];
            app.TargetX.Value = 45;
            app.YEditField_4Label_2 = uilabel(app.TargetPanel);
            app.YEditField_4Label_2.Text = 'Y';
            app.TargetY = uieditfield(app.TargetPanel, 'numeric');
            app.TargetY.Limits = [0 50];
            app.TargetY.Value = 15;
            app.ZEditFieldLabel_2 = uilabel(app.TargetPanel);
            app.ZEditFieldLabel_2.Text = 'Z';
            app.TargetZ = uieditfield(app.TargetPanel, 'numeric');
            app.TargetZ.Limits = [0 50];
            app.TargetZ.Value = 33;
            app.FixedObstaclesListPanel = uipanel(app.RightPanel);
            app.FixedObstaclesListPanel.Title = 'Fixed Obstacles List';
            app.XEditField_3Label_2 = uilabel(app.FixedObstaclesListPanel);
            app.XEditField_3Label_2.Text = 'X';
            app.FixedXField = uieditfield(app.FixedObstaclesListPanel, 'numeric');
            app.FixedXField.Limits = [0 50];
            app.FixedXField.ValueChangedFcn = createCallbackFcn(app, @FixedXFieldValueChanged, true);
            app.FixedXField.Value = 15;
            app.FixedBox = uilistbox(app.FixedObstaclesListPanel);
            app.FixedBox.Items = {'Obstacle 1', 'Obstacle 2', 'Obstacle 3', 'Obstacle 4', 'Obstacle 5', 'Obstacle 6'};
            app.FixedBox.ValueChangedFcn = createCallbackFcn(app, @FixedBoxValueChanged, true);
            app.FixedBox.Value = 'Obstacle 1';
            app.YEditFieldLabel_2 = uilabel(app.FixedObstaclesListPanel);
            app.YEditFieldLabel_2.Text = 'Y';
            app.FixedYField = uieditfield(app.FixedObstaclesListPanel, 'numeric');
            app.FixedYField.Limits = [0 50];
            app.FixedYField.ValueChangedFcn = createCallbackFcn(app, @FixedYFieldValueChanged, true);
            app.FixedYField.Value = 15;
            app.RLabel_2 = uilabel(app.FixedObstaclesListPanel);
            app.RLabel_2.Text = 'R';
            app.FixedRField = uieditfield(app.FixedObstaclesListPanel, 'numeric');
            app.FixedRField.Limits = [0 50];
            app.FixedRField.ValueChangedFcn = createCallbackFcn(app, @FixedRFieldValueChanged, true);
            app.FixedRField.Value = 2;
            app.AddFixedButton = uibutton(app.FixedObstaclesListPanel, 'push');
            app.AddFixedButton.ButtonPushedFcn = createCallbackFcn(app, @AddFixedButtonPushed, true);
            app.AddFixedButton.Text = '+';
            app.ZLabel_2 = uilabel(app.FixedObstaclesListPanel);
            app.ZLabel_2.Text = 'Z';
            app.FixedZField = uieditfield(app.FixedObstaclesListPanel, 'numeric');
            app.FixedZField.Limits = [0 50];
            app.FixedZField.ValueChangedFcn = createCallbackFcn(app, @FixedZFieldValueChanged, true);
            app.FixedZField.Value = 30;
            app.RemoveFixedButton = uibutton(app.FixedObstaclesListPanel, 'push');
            app.RemoveFixedButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveFixedButtonPushed, true);
            app.RemoveFixedButton.Text = '-';
            app.ResetButton = uibutton(app.RightPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.FindPathButton = uibutton(app.RightPanel, 'state');
            app.FindPathButton.ValueChangedFcn = createCallbackFcn(app, @FindPathButtonValueChanged, true);
            app.FindPathButton.Text = 'Find Path';
            app.PresetsPanel = uipanel(app.RightPanel);
            app.PresetsPanel.Title = 'Presets';
            app.NoObstaclesButton = uibutton(app.PresetsPanel, 'push');
            app.NoObstaclesButton.ButtonPushedFcn = createCallbackFcn(app, @NoObstaclesButtonPushed, true);
            app.NoObstaclesButton.Text = 'No Obstacles';
            app.TallBuildingsButton = uibutton(app.PresetsPanel, 'push');
            app.TallBuildingsButton.ButtonPushedFcn = createCallbackFcn(app, @TallBuildingsButtonPushed, true);
            app.TallBuildingsButton.Text = 'Tall Buildings';
            app.CrowdedAirspaceButton = uibutton(app.PresetsPanel, 'push');
            app.CrowdedAirspaceButton.ButtonPushedFcn = createCallbackFcn(app, @CrowdedAirspaceButtonPushed, true);
            app.CrowdedAirspaceButton.Text = 'Crowded Airspace';
            app.DefaultButton = uibutton(app.PresetsPanel, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Text = 'Default';
            app.TrickyLandingButton = uibutton(app.PresetsPanel, 'push');
            app.TrickyLandingButton.ButtonPushedFcn = createCallbackFcn(app, @TrickyLandingButtonPushed, true);
            app.TrickyLandingButton.Text = 'Tricky Landing';
            app.ClearDayClimbButton = uibutton(app.PresetsPanel, 'push');
            app.ClearDayClimbButton.ButtonPushedFcn = createCallbackFcn(app, @ClearDayClimbButtonPushed, true);
            app.ClearDayClimbButton.Text = 'Clear Day Climb';
            app.PausebetweenstepsLabel = uilabel(app.PresetsPanel);
            app.PausebetweenstepsLabel.Text = 'Pause between steps:';
            app.PauseField = uieditfield(app.PresetsPanel, 'numeric');
            app.PauseField.Limits = [0 10];
            app.PauseField.Value = 0.25;
            app.sLabel = uilabel(app.PresetsPanel);
            app.sLabel.Text = 's';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AStarApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end